classdef DOA_estimation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AOAESITMATEDVALUESLabel      matlab.ui.control.Label
        MUSICEditFieldLabel          matlab.ui.control.Label
        MUSICEditField               matlab.ui.control.EditField
        CaponEditFieldLabel          matlab.ui.control.Label
        CaponEditField               matlab.ui.control.EditField
        FourierEditField             matlab.ui.control.EditField
        FourierEditFieldLabel        matlab.ui.control.Label
        MUSICCheckBox                matlab.ui.control.CheckBox
        CaponCheckBox                matlab.ui.control.CheckBox
        FourierCheckBox              matlab.ui.control.CheckBox
        SNRdBEditField               matlab.ui.control.NumericEditField
        SNRdBEditFieldLabel          matlab.ui.control.Label
        NoofsamplesEditField         matlab.ui.control.NumericEditField
        NoofsamplesEditFieldLabel    matlab.ui.control.Label
        NoofAntennasEditField        matlab.ui.control.NumericEditField
        NoofAntennasEditFieldLabel   matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        RUNDOAESTIMATIONButton       matlab.ui.control.Button
        CarrierFrequencyHzEditField  matlab.ui.control.NumericEditField
        NoofSourcesEditField         matlab.ui.control.NumericEditField
        AnglesofArrivalEditField     matlab.ui.control.EditField
        AnglesofArrivalLabel         matlab.ui.control.Label
        CarrierFrequencyHzEditFieldLabel  matlab.ui.control.Label
        NoofSourcesEditFieldLabel    matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNDOAESTIMATIONButton
        function RUNDOAESTIMATIONButtonPushed(app, event)
            % Get all user inputs
            K = app.NoofSourcesEditField.Value;
            M = app.NoofAntennasEditField.Value;
            fc = app.CarrierFrequencyHzEditField.Value;
            N = app.NoofsamplesEditField.Value;
            snr = app.SNRdBEditField.Value;
            inputStr = app.AnglesofArrivalEditField.Value;
            try
                % Convert comma-separated string to numeric array
                theta_aoa = str2num(inputStr); %#ok<ST2NM>
                
                % Validation
                if isempty(theta_aoa)
                    uialert(app.UIFigure, 'Invalid input. Please enter angles like: 30, 45, 60', 'Input Error');
                    return;
                end
                if length(theta_aoa) ~= app.NoofSourcesEditField.Value
                    uialert(app.UIFigure, 'Number of angles must match number of sources.', 'Mismatch');
                    return;
                end
        
                % Store or process angles
                disp("Source angles in degrees: ");
                disp(theta_aoa);
        
                % Optionally convert to radians:
                theta_aoa_rad = deg2rad(theta_aoa);
        
            catch
                uialert(app.UIFigure, 'Error processing angles. Use format: 30,45,60', 'Input Error');
            end
        
            % Constants
            j = sqrt(-1);
            c = 3e8;
            lambda = c / fc;
            d = lambda / 2;
        
            % Generate signal samples
            signal_indices = randi(4, K, N);
            signal_samples = exp(j * (2 * pi * signal_indices / 4 + pi/4));
        
            % Generate steering vectors
            gen_ster_vec = zeros(M, K);
            for k = 1:K
                for m = 1:M
                    gen_ster_vec(m, k) = exp(j * 2 * pi * d * (m-1) * sin(theta_aoa_rad(k)) / lambda);
                end
            end
        
            % Add noise
            snr_linear = 10^(snr/10);
            signal_power = 1;
            noise_power = signal_power / snr_linear;
            noise_samples = sqrt(noise_power/2) * (randn(M, N) + 1j * randn(M, N));
        
            % Received signal
            received_signal_samples = gen_ster_vec * signal_samples + noise_samples;
            Rxx = (received_signal_samples * received_signal_samples') / N;
            Rxx_inv = inv(Rxx);
        
            [V, D] = eig(Rxx);
            [eig_vals, ind] = sort(diag(D));
            Vs = V(:, ind);
            eig_vals_desc = sort(eig_vals, 'descend');
        
            % Estimate number of sources
            [~, sources_estimate] = number_sources_estimation(M, N, eig_vals_desc);
        
            Vn = Vs(:, 1:M-sources_estimate);
        
            % Scan over angles
            angle_scan_range = -90:0.1:90;
            num_angles = length(angle_scan_range);
            cf_fourier = zeros(num_angles, 1);
            cf_capon   = zeros(num_angles, 1);
            cf_music   = zeros(num_angles, 1);
        
            for i = 1:num_angles
                angle_rad = deg2rad(angle_scan_range(i));
                a = exp(j * 2 * pi * d * (0:M-1).' * sin(angle_rad) / lambda);
                cf_fourier(i) = a' * Rxx * a;
                cf_capon(i) = 1 / (a' * Rxx_inv * a);
                cf_music(i) = 1 / (a' * Vn * Vn' * a);
            end
        
            % Convert to dB
            cf_fourier_dB = 10 * log10(abs(cf_fourier));
            cf_capon_dB = 10 * log10(abs(cf_capon));
            cf_music_dB = 10 * log10(abs(cf_music));
        
            % Normalize
            cf_fourier_dB = cf_fourier_dB - max(cf_fourier_dB);
            cf_capon_dB = cf_capon_dB - max(cf_capon_dB);
            cf_music_dB = cf_music_dB - max(cf_music_dB);
            
            % Peak detection and aoa estimation
            [aoa_fourier, peak_vals_fourier] = find_peaks_doa(angle_scan_range, cf_fourier_dB,sources_estimate);
            [aoa_capon, peak_vals_capon]     = find_peaks_doa(angle_scan_range, cf_capon_dB,sources_estimate);
            [aoa_music, peak_vals_music]     = find_peaks_doa(angle_scan_range, cf_music_dB,sources_estimate);
        
            % Format estimated angles as comma-separated strings
            app.FourierEditField.Value = strjoin(string(round(aoa_fourier, 1)), ', ');
            app.CaponEditField.Value  = strjoin(string(round(aoa_capon, 1)), ', ');
            app.MUSICEditField.Value   = strjoin(string(round(aoa_music, 1)), ', ');


            cla(app.UIAxes); % clear plot
            hold(app.UIAxes, 'on');
            
            % Plot each method only if the corresponding checkbox is selected
            legend_entries = {};
            
            if app.FourierCheckBox.Value
                plot(app.UIAxes, angle_scan_range, cf_fourier_dB, 'b', 'LineWidth', 1.5);
                legend_entries{end+1} = 'Fourier';
            end
            
            if app.CaponCheckBox.Value
                plot(app.UIAxes, angle_scan_range, cf_capon_dB, 'r--', 'LineWidth', 1.5);
                legend_entries{end+1} = 'Capon';
            end
            
            if app.MUSICCheckBox.Value
                plot(app.UIAxes, angle_scan_range, cf_music_dB, 'g-', 'LineWidth', 1.5);
                legend_entries{end+1} = 'MUSIC';
            end
            
            % Draw vertical reference lines for ground truth AOAs
            for k = 1:length(theta_aoa)
                x = theta_aoa(k);
                y_limits = ylim(app.UIAxes);
                plot(app.UIAxes, [x x], y_limits, 'k--', 'LineWidth', 1);
            end
            
            xlabel(app.UIAxes, '\theta (degrees)');
            ylabel(app.UIAxes, 'Normalized Spectrum (dB)');
            title(app.UIAxes, 'DOA Spectrum');
            legend(app.UIAxes, legend_entries, 'Location', 'best');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Performance of DOA estimates ')
            xlabel(app.UIAxes, '(\theta in \circ)')
            ylabel(app.UIAxes, 'Spectral value(dB)')
            zlabel(app.UIAxes, 'Z')
            app.NoofSourcesEditFieldLabel = uilabel(app.UIFigure);
            app.NoofSourcesEditFieldLabel.Text = 'No of Sources';
            app.CarrierFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.CarrierFrequencyHzEditFieldLabel.Text = 'Carrier Frequency(Hz)';
            app.AnglesofArrivalLabel = uilabel(app.UIFigure);
            app.AnglesofArrivalLabel.Text = 'Angles of Arrival (°)';
            app.AnglesofArrivalEditField = uieditfield(app.UIFigure, 'text');
            app.AnglesofArrivalEditField.Value = '10,15,30,60';
            app.NoofSourcesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofSourcesEditField.Value = 4;
            app.CarrierFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarrierFrequencyHzEditField.Value = 10000000;
            app.RUNDOAESTIMATIONButton = uibutton(app.UIFigure, 'push');
            app.RUNDOAESTIMATIONButton.ButtonPushedFcn = createCallbackFcn(app, @RUNDOAESTIMATIONButtonPushed, true);
            app.RUNDOAESTIMATIONButton.Text = 'RUN DOA ESTIMATION';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'DOA ESTIMATION AND PERFORMANCE COMPARISON'; '(for ULA ANTENNA GEOMETRY)'};
            app.NoofAntennasEditFieldLabel = uilabel(app.UIFigure);
            app.NoofAntennasEditFieldLabel.Text = 'No of Antennas';
            app.NoofAntennasEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofAntennasEditField.Value = 10;
            app.NoofsamplesEditFieldLabel = uilabel(app.UIFigure);
            app.NoofsamplesEditFieldLabel.Text = 'No of samples';
            app.NoofsamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofsamplesEditField.Value = 200;
            app.SNRdBEditFieldLabel = uilabel(app.UIFigure);
            app.SNRdBEditFieldLabel.Text = 'SNR(dB)';
            app.SNRdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.SNRdBEditField.Value = 20;
            app.FourierCheckBox = uicheckbox(app.UIFigure);
            app.FourierCheckBox.Text = 'Fourier';
            app.FourierCheckBox.Value = true;
            app.CaponCheckBox = uicheckbox(app.UIFigure);
            app.CaponCheckBox.Text = 'Capon';
            app.CaponCheckBox.Value = true;
            app.MUSICCheckBox = uicheckbox(app.UIFigure);
            app.MUSICCheckBox.Text = 'MUSIC';
            app.MUSICCheckBox.Value = true;
            app.FourierEditFieldLabel = uilabel(app.UIFigure);
            app.FourierEditFieldLabel.Text = 'Fourier';
            app.FourierEditField = uieditfield(app.UIFigure, 'text');
            app.FourierEditField.Editable = 'off';
            app.CaponEditField = uieditfield(app.UIFigure, 'text');
            app.CaponEditField.Editable = 'off';
            app.CaponEditFieldLabel = uilabel(app.UIFigure);
            app.CaponEditFieldLabel.Text = 'Capon';
            app.MUSICEditField = uieditfield(app.UIFigure, 'text');
            app.MUSICEditField.Editable = 'off';
            app.MUSICEditFieldLabel = uilabel(app.UIFigure);
            app.MUSICEditFieldLabel.Text = 'MUSIC';
            app.AOAESITMATEDVALUESLabel = uilabel(app.UIFigure);
            app.AOAESITMATEDVALUESLabel.Text = 'AOA ESITMATED VALUES';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOA_estimation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end